classdef codeBaseV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectDropDown                 matlab.ui.control.DropDown
        SelectDropDownLabel            matlab.ui.control.Label
        CouplingDropDown               matlab.ui.control.DropDown
        CouplingDropDownLabel          matlab.ui.control.Label
        InvalidPathLabel               matlab.ui.control.Label
        NonDriveEndProblemTextArea     matlab.ui.control.TextArea
        NonDriveEndProblemTextAreaLabel  matlab.ui.control.Label
        DriveEndProblemTextArea        matlab.ui.control.TextArea
        DriveEndProblemTextAreaLabel   matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NonDriveEndBearingEditField    matlab.ui.control.EditField
        NonDriveEndBearingEditFieldLabel  matlab.ui.control.Label
        DriveEndBearingEditField       matlab.ui.control.EditField
        DriveEndBearingEditFieldLabel  matlab.ui.control.Label
        NDdbcEditField                 matlab.ui.control.NumericEditField
        NDdbcEditFieldLabel            matlab.ui.control.Label
        NDdbmEditField                 matlab.ui.control.NumericEditField
        NDdbmEditFieldLabel            matlab.ui.control.Label
        DdbmEditField                  matlab.ui.control.NumericEditField
        DdbmEditFieldLabel             matlab.ui.control.Label
        DdbcEditField                  matlab.ui.control.NumericEditField
        DdbcEditFieldLabel             matlab.ui.control.Label
        SPmCheckBox                    matlab.ui.control.CheckBox
        S712RowEndEditField            matlab.ui.control.NumericEditField
        S712RowEndEditFieldLabel       matlab.ui.control.Label
        S712RowStartEditField          matlab.ui.control.NumericEditField
        S712RowStartEditFieldLabel     matlab.ui.control.Label
        Sheet7to12Label                matlab.ui.control.Label
        Sheet1to6Label                 matlab.ui.control.Label
        S16RowEndEditField             matlab.ui.control.NumericEditField
        S16RowEndEditFieldLabel        matlab.ui.control.Label
        S16RowStartEditField           matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        S16RowStartLabel               matlab.ui.control.Label
        DataSheetRangeCheckBox         matlab.ui.control.CheckBox
        NonDriveEndConditionEditField  matlab.ui.control.EditField
        NonDriveEndConditionEditFieldLabel  matlab.ui.control.Label
        OpenFileButton                 matlab.ui.control.Button
        AnalyzeButton                  matlab.ui.control.Button
        DriveEndConditionEditFieldLabel  matlab.ui.control.Label
        PlotCheckBox                   matlab.ui.control.CheckBox
        ValueTableCheckBox             matlab.ui.control.CheckBox
        MotorTypeDropDown              matlab.ui.control.DropDown
        MotorTypeDropDownLabel         matlab.ui.control.Label
        FoundationDropDown             matlab.ui.control.DropDown
        FoundationDropDownLabel        matlab.ui.control.Label
        MonthEditField                 matlab.ui.control.NumericEditField
        MonthEditFieldLabel            matlab.ui.control.Label
        DayEditField                   matlab.ui.control.NumericEditField
        DayEditFieldLabel              matlab.ui.control.Label
        ImbalanceCheckBox              matlab.ui.control.CheckBox
        ExtremeEditField               matlab.ui.control.NumericEditField
        ExtremeEditFieldLabel          matlab.ui.control.Label
        SeriousEditField               matlab.ui.control.NumericEditField
        SeriousEditFieldLabel          matlab.ui.control.Label
        ModerateEditField              matlab.ui.control.NumericEditField
        ModerateEditFieldLabel         matlab.ui.control.Label
        SlightEditField                matlab.ui.control.NumericEditField
        SlightEditFieldLabel           matlab.ui.control.Label
        RPMEditField                   matlab.ui.control.NumericEditField
        RPMEditFieldLabel              matlab.ui.control.Label
        DriveEndConditionEditField     matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = public)

    end

    properties (Access = private)
        RPM; % Store RPM value of the machine
        RPS; % RPS

        % Imbalance Parameters
        slight; moderate; serious; extreme;

        % Store motorType
        motorType;

        % Store Foundation type
        foundationType;

        % Will hold required parameters
        F; DH; DV; DA; NDH; NDV; NDA; t1; t; % Description

        % FilePath
        filePath;

        % FOR Data Sheet Range
        S16RowS; S16RowE; S712RowS; S712RowE;

        % SP data
        Ddbm; Ddbc; NDdbm; NDdbc;
        
        % Periodical Data
        PDH; PDV; PDA; PNDH; PNDV; PNDA;
        
        % Couple
        couple;
        
        % Select Figure
        SelectFigure;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Default FilePath
            app.filePath = "";

            % Setting up the Clock
            CLOCK = clock;
            app.MonthEditField.Value = CLOCK(2);
            app.DayEditField.Value = CLOCK(3);

            % Checking the initial "Imbalance BOX Condition"
            app.ImbalanceCheckBox.Value = false; % set value to True
            % Feilds will not be visible
            app.SlightEditFieldLabel.Visible = 'off';
            app.ModerateEditFieldLabel.Visible = 'off';
            app.SeriousEditFieldLabel.Visible = 'off';
            app.ExtremeEditFieldLabel.Visible = 'off';

            app.SlightEditField.Visible = 'off';
            app.ModerateEditField.Visible = 'off';
            app.SeriousEditField.Visible = 'off';
            app.ExtremeEditField.Visible = 'off';


            % Initial "Imbalance" Values
            app.SlightEditField.Value = 0; % Initial Value
            app.ModerateEditField.Value = 2.5; % Initial Value
            app.SeriousEditField.Value = 5; % Initial Value
            app.ExtremeEditField.Value = 15.8; % Initial Value
            % Save the values to the variables
            app.slight = app.SlightEditField.Value;
            app.moderate = app.ModerateEditField.Value;
            app.serious = app.SeriousEditField.Value;
            app.extreme = app.ExtremeEditField.Value;


            % Initial RPM
            app.RPMEditField.Value = 2970; % Initial Value
            app.RPM = app.RPMEditField.Value;
            app.RPS = app.RPMEditField.Value/60; % RPS | also calls 1x value of the machine

            % Initial Motor Type
            app.MotorTypeDropDown.Value = 'Over Hang'; % Initial Value

            % Initial Foundation Type
            app.FoundationDropDown.Value = 'Rigid'; % Initial Value

            % Table Enable Box will be false | uncheck
            app.ValueTableCheckBox.Value = true;
            app.UITable.Visible = 'on'; % So, table visibility OFF

            % Plot Enable BOX will be false | uncheck
            app.PlotCheckBox.Value = false;
            app.UIAxes.Visible = 'off'; % So, plot visibility OFF

            % Values for Data Sheet Range
            app.S16RowStartEditField.Value = 9;
            app.S16RowEndEditField.Value = 3000;
            app.S712RowStartEditField.Value = 9;
            app.S712RowEndEditField.Value = 300;

            app.S16RowS = app.S16RowStartEditField.Value;
            app.S16RowE = app.S16RowEndEditField.Value;
            app.S712RowS = app.S712RowStartEditField.Value;
            app.S712RowE = app.S712RowEndEditField.Value;

            % Checking initial Data Sheet Range Check box condition
            app.DataSheetRangeCheckBox.Value = true;
            % Then ...........
            % Edit Field visibility on
            app.S16RowStartEditField.Visible = true;
            app.S16RowEndEditField.Visible = true;
            app.S712RowStartEditField.Visible = true;
            app.S712RowEndEditField.Visible = true;

            % Label Field visibility on
            app.S16RowStartLabel.Visible = true;
            app.S16RowEndEditFieldLabel.Visible = true;
            app.S712RowStartEditFieldLabel.Visible = true;
            app.S712RowEndEditFieldLabel.Visible = true;

            % Trun on Editable access
            app.S16RowStartEditField.Editable = true;
            app.S16RowEndEditField.Editable = true;
            app.S712RowStartEditField.Editable = true;
            app.S712RowEndEditField.Editable = true;

            % Label off
            app.Sheet1to6Label.Visible = true;
            app.Sheet7to12Label.Visible = true;


            % Cheching SP box Condition
            app.SPmCheckBox.Value = true;
            % Trun on the associated fields
            app.DdbcEditFieldLabel.Visible = true;
            app.DdbmEditFieldLabel.Visible = true;
            app.NDdbcEditFieldLabel.Visible = true;
            app.NDdbmEditFieldLabel.Visible = true;

            app.DdbcEditField.Visible = true;
            app.DdbmEditField.Visible = true;
            app.NDdbcEditField.Visible = true;
            app.NDdbmEditField.Visible = true;

            app.DdbcEditField.Editable = true;
            app.DdbmEditField.Editable = true;
            app.NDdbcEditField.Editable = true;
            app.NDdbmEditField.Editable = true;


            % SPm
            app.DdbcEditField.Value = 0;
            app.DdbmEditField.Value = 0;
            app.NDdbcEditField.Value = 0;
            app.NDdbmEditField.Value = 0;

            app.Ddbc = app.DdbcEditField.Value;
            app.Ddbm = app.DdbmEditField.Value;
            app.NDdbc = app.NDdbcEditField.Value;
            app.NDdbm = app.NDdbmEditField.Value;
            
            
            % Couple
            app.CouplingDropDown.Value = "Integrated";
            app.couple = app.CouplingDropDown.Value;
            
            
            % Figure Select
            app.SelectDropDownLabel.Visible = 'off';
            app.SelectDropDown.Visible = 'off';
            app.SelectDropDown.Enable = 'off';
            
            app.SelectDropDown.Value = "All";
            app.SelectFigure = app.SelectDropDown.Value;
        end

        % Value changed function: ImbalanceCheckBox
        function ImbalanceCheckBoxValueChanged(app, event)

            % CHeck the condition of the Imbalance Check box
            if app.ImbalanceCheckBox.Value == false
                % Turn Off field's edit access
                app.SlightEditField.Editable = 'off';
                app.ModerateEditField.Editable = 'off';
                app.SeriousEditField.Editable = 'off';
                app.ExtremeEditField.Editable = 'off';

                % Feilds will not be visible
                app.SlightEditFieldLabel.Visible = 'off';
                app.ModerateEditFieldLabel.Visible = 'off';
                app.SeriousEditFieldLabel.Visible = 'off';
                app.ExtremeEditFieldLabel.Visible = 'off';

                app.SlightEditField.Visible = 'off';
                app.ModerateEditField.Visible = 'off';
                app.SeriousEditField.Visible = 'off';
                app.ExtremeEditField.Visible = 'off';
            else
                % Fields will be visible
                app.SlightEditFieldLabel.Visible = 'on';
                app.ModerateEditFieldLabel.Visible = 'on';
                app.SeriousEditFieldLabel.Visible = 'on';
                app.ExtremeEditFieldLabel.Visible = 'on';

                app.SlightEditField.Visible = 'on';
                app.ModerateEditField.Visible = 'on';
                app.SeriousEditField.Visible = 'on';
                app.ExtremeEditField.Visible = 'on';

                % Turn On field's edit access
                app.SlightEditField.Editable = 'on';
                app.ModerateEditField.Editable = 'on';
                app.SeriousEditField.Editable = 'on';
                app.ExtremeEditField.Editable = 'on';
            end
        end

        % Value changed function: RPMEditField
        function RPMEditFieldValueChanged(app, event)
            app.RPM = app.RPMEditField.Value;
            app.RPS = app.RPM/60;
        end

        % Value changed function: SlightEditField
        function SlightEditFieldValueChanged(app, event)
            app.slight = app.SlightEditField.Value;
        end

        % Value changed function: ModerateEditField
        function ModerateEditFieldValueChanged(app, event)
            app.moderate = app.ModerateEditField.Value;
        end

        % Value changed function: SeriousEditField
        function SeriousEditFieldValueChanged(app, event)
            app.serious = app.SeriousEditField.Value;
        end

        % Value changed function: ExtremeEditField
        function ExtremeEditFieldValueChanged(app, event)
            app.extreme = app.ExtremeEditField.Value;
        end

        % Value changed function: MotorTypeDropDown
        function MotorTypeDropDownValueChanged(app, event)
            app.motorType = app.MotorTypeDropDown.Value;
        end

        % Value changed function: FoundationDropDown
        function FoundationDropDownValueChanged(app, event)
            app.foundationType = app.FoundationDropDown.Value;
        end

        % Value changed function: ValueTableCheckBox
        function ValueTableCheckBoxValueChanged(app, event)
            if app.ValueTableCheckBox.Value == true
                app.UITable.Visible = 'on';
                app.PlotCheckBox.Value = false;
                PlotCheckBoxValueChanged(app, event);
            else
                app.UITable.Visible = 'off';
                app.PlotCheckBox.Value = true;
                PlotCheckBoxValueChanged(app, event);
                app.UIAxes.Visible = 'on';
            end
        end

        % Value changed function: PlotCheckBox
        function PlotCheckBoxValueChanged(app, event)
            if app.PlotCheckBox.Value == false
                % Deactivate the Select Figure
                app.SelectDropDown.Visible = 'off';
                app.SelectDropDown.Enable = 'off';
                app.SelectDropDownLabel.Visible = 'off';
                
                app.UIAxes.Visible = 'off';
                set(app.ValueTableCheckBox, 'Value', true);
                app.UITable.Visible = 'on';
            else
                % Activate the Select Figure
                app.SelectDropDown.Visible = 'on';
                app.SelectDropDown.Enable = 'on';
                app.SelectDropDownLabel.Visible = 'on';
                
                set(app.ValueTableCheckBox, 'Value', false);
                app.UITable.Visible = 'off';
                
                app.UIAxes.Visible = 'on';

                if (~isempty(app.t))
                    plot(app.UIAxes, table2array(app.t(:,1)), [table2array(app.t(:,2)), ...
                        table2array(app.t(:,3)), table2array(app.t(:,4)), ...
                        table2array(app.t(:,5)), table2array(app.t(:,6))]);
                    legend(app.UIAxes, 'DH', 'DV', 'DA', 'NH', 'NV', 'NA');
                end
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            if (~(app.filePath == ""))
                % Turn off the invalid text
                app.InvalidPathLabel.Visible = 'off';
                app.InvalidPathLabel.BackgroundColor = 'white';
                
                %% Sheets Name
                SheetNames = ["MSDE-H", "MSDE-V", "MSDE-A", "MSNDE-H", "MSNDE-V", "MSNDE-A",...
                    "PDE-H", "PDE-V", "PDE-A", "PNDE-H", "PNDE-V", "PNDE-A",...
                    "SPDE", "SPNDE"];

                %% FOR Sheet 1 to 6
                tb2 = table;
                for i = 1:6
                    if i < 2
                        opts = spreadsheetImportOptions("NumVariables", 2);
                        opts.DataRange = "C"+num2str(app.S16RowS)+":"+"D"+num2str(app.S16RowE);
                        opts.VariableTypes = ["double", "double"];
                    else
                        opts = spreadsheetImportOptions("NumVariables", 1);
                        opts.DataRange = "D"+num2str(app.S16RowS)+":"+"D"+num2str(app.S16RowE);
                        opts.VariableTypes = "double";
                    end
                    % Specify sheet
                    opts.Sheet = SheetNames(i);
                    tb1 = readtable(app.filePath, opts, "UseExcel", false);

                    if i < 2
                        tb2(:,i) = tb1(:,1);
                        tb2(:,i+1) = tb1(:,2);
                    else
                        tb2(:,i+1) = tb1(:,1);
                    end
                end

                %% Convert to output type
                app.F = rmmissing(table2array(tb2(:,1)));
                app.DH = rmmissing(table2array(tb2(:,2)));
                app.DV = rmmissing(table2array(tb2(:,3)));
                app.DA = rmmissing(table2array(tb2(:,4)));
                app.NDH = rmmissing(table2array(tb2(:,5)));
                app.NDV = rmmissing(table2array(tb2(:,6)));
                app.NDA = rmmissing(table2array(tb2(:,7)));

                app.t1 = table;
                app.t = table(app.F, app.DH, app.DV, app.DA, app.NDH, app.NDV, app.NDA);
                q = 1;
                for p = 1:length(table2array(app.t(:,1)))
                    if (table2array(app.t(p, 1)) > 0)
                        if (mod(table2array(app.t(p, 1)), app.RPS) == 0)
                            app.t1(q,:) = app.t(p,:);
                            q = q+1;
                        end
                    end
                end

                % Clear temporary variables
                clear opts F DH DV DA NH NV NA tb1 tb2 q

                % Store modified table value
                app.F = table2array(app.t1(:,1));
                app.DH = table2array(app.t1(:,2));
                app.DV = table2array(app.t1(:,3));
                app.DA = table2array(app.t1(:,4));
                app.NDH = table2array(app.t1(:,5));
                app.NDV = table2array(app.t1(:,6));
                app.NDA = table2array(app.t1(:,7));


                %% FOR Sheet 7 to 12
                tb2 = table;
                for i = 7:12
                    opts = spreadsheetImportOptions("NumVariables", 1);
                    opts.DataRange = "E"+num2str(app.S712RowS)+":"+"E"+num2str(app.S712RowE);
                    
                    % Specify sheet
                    opts.Sheet = SheetNames(i);
                    tb1 = readtable(app.filePath, opts, "UseExcel", false);

                    tb2(:,i - 6) = tb1(:,1);
                end

                % Split Data and remove mm/s
                last = [];
                for i = 1:(app.S712RowE-app.S712RowS)
                    for j = 1:6
                        tmpV = split(table2array(tb2(i,j)), " ");
                        last(i,j) = str2double(tmpV(1));
                    end
                end

                app.PDH = rmmissing(last(:,1));
                app.PDV = rmmissing(last(:,2));
                app.PDA = rmmissing(last(:,3));
                app.PNDH = rmmissing(last(:,4));
                app.PNDV = rmmissing(last(:,5));
                app.PNDA = rmmissing(last(:,6));

                % Clear temporary variables
                clear i opts j tmpV last tb1 tb2 app.filePath
                

                % Will update the data imidiately
                app.ValueTableCheckBox.Value = true;
                app.UITable.Visible = 'on';
                app.UITable.Data = app.t1; % Set the Table with new data
                app.PlotCheckBox.Value = false;
                PlotCheckBoxValueChanged(app, event);

                % Will update the plot imidiately

                if (~isempty(app.t))
                    plot(app.UIAxes, table2array(app.t(:,1)), [table2array(app.t(:,2)), ...
                        table2array(app.t(:,3)), table2array(app.t(:,4)), ...
                        table2array(app.t(:,5)), table2array(app.t(:,6))]);
                    legend(app.UIAxes, 'DH', 'DV', 'DA', 'NH', 'NV', 'NA');
                end


                % Condistion Status | is it? Slight or Moderate or Serious or Extreme
                % 1X positional values
                max1D = max(table2array(app.t1(1,2:4)));
                max1ND = max(table2array(app.t1(1,5:7)));

                % Checking the Drive End part
                if (max1D < app.moderate)
                    app.DriveEndConditionEditField.Value = 'Slight';
                    app.DriveEndConditionEditField.BackgroundColor = "Green";
                elseif (max1D >= app.moderate && max1D < app.serious)
                    app.DriveEndConditionEditField.Value = 'Moderate';
                    app.DriveEndConditionEditField.BackgroundColor = "cyan";
                elseif (max1D >= app.serious && max1D < app.extreme)
                    app.DriveEndConditionEditField.Value = 'Serious';
                    app.DriveEndConditionEditField.BackgroundColor = "yellow";
                elseif (max1D >= app.extreme)
                    app.DriveEndConditionEditField.Value = 'Extreme';
                    app.DriveEndConditionEditField.BackgroundColor = "Red";
                end

                % Checking the Non Drive End part
                if (max1ND < app.moderate)
                    app.NonDriveEndConditionEditField.Value = 'Slight';
                    app.NonDriveEndConditionEditField.BackgroundColor = "Green";
                elseif (max1ND >= app.moderate && max1ND < app.serious)
                    app.NonDriveEndConditionEditField.Value = 'Moderate';
                    app.NonDriveEndConditionEditField.BackgroundColor = "cyan";
                elseif (max1ND >= app.serious && max1ND < app.extreme)
                    app.NonDriveEndConditionEditField.Value = 'Serious';
                    app.NonDriveEndConditionEditField.BackgroundColor = "yellow";
                elseif (max1ND >= app.extreme)
                    app.NonDriveEndConditionEditField.Value = 'Extreme';
                    app.NonDriveEndConditionEditField.BackgroundColor = "Red";
                end

                % For SP
                % Drive End
                if (app.Ddbc > 0 || app.Ddbm > 0)
                    app.DriveEndBearingEditField.Value  = "Alert!";
                    app.DriveEndBearingEditField.BackgroundColor = "Red";
                end
                if (app.Ddbc < 0 && app.Ddbm < 0)
                    if ((app.Ddbc < app.Ddbm) && (app.Ddbm - app.Ddbc) >= 10)
                        app.DriveEndBearingEditField.Value  = "Alert!";
                        app.DriveEndBearingEditField.BackgroundColor = "Red";
                    elseif ((app.Ddbc > app.Ddbm) && (app.Ddbc - app.Ddbm) >= 10)
                        app.DriveEndBearingEditField.Value  = "Alert!";
                        app.DriveEndBearingEditField.BackgroundColor = "Red";
                    else
                        app.DriveEndBearingEditField.Value  = "Normal";
                        app.DriveEndBearingEditField.BackgroundColor = "Green";
                    end
                end
                % Non Drive End
                if (app.NDdbc > 0 || app.NDdbm > 0)
                    app.NonDriveEndBearingEditField.Value  = "Alert!";
                    app.NonDriveEndBearingEditField.BackgroundColor = "Red";
                end
                if (app.NDdbc < 0 && app.NDdbm < 0)
                    if ((app.NDdbc < app.NDdbm) && (app.NDdbm - app.NDdbc) >= 10)
                        app.NonDriveEndBearingEditField.Value  = "Alert!";
                        app.NonDriveEndBearingEditField.BackgroundColor = "Red";
                    elseif ((app.NDdbc > app.NDdbm) && (app.NDdbc - app.NDdbm) >= 10)
                        app.NonDriveEndBearingEditField.Value  = "Alert!";
                        app.NonDriveEndBearingEditField.BackgroundColor = "Red";
                    else
                        app.NonDriveEndBearingEditField.Value  = "Normal";
                        app.NonDriveEndBearingEditField.BackgroundColor = "Green";
                    end
                end


                % For Problem Statement
                marginValue = 2;
                Dproblem = "";
                NDproblem = "";

                %% DRIVE END
                % Mis Alignment and Bent Shaft
                if (((app.DH(1,1)>marginValue && app.DH(2,1)>0) && app.DH(3,1)>0) &&...
                        (app.DV(1,1)>marginValue && app.DV(2,1)>0) &&...
                        (app.DA(1,1)>marginValue && app.DA(2,1)>0))
                    if app.couple == "Flexible"
                        % % Mis Alignment
                        Dproblem = Dproblem + "--" + "Mis Alignment";
                    else % Bent Shaft
                        Dproblem = Dproblem + "--" + "Bent Shaft/Cocked Bearing";
                    end
                end
                % Structural Looseness
                if (app.DH(1,1)>0 && app.DV(1,1)>0 && app.DH(1,1)>app.DV(1,1))
                    Dproblem = Dproblem + "--" + "Vertical Looseness/Severe Foundation Flexibility";
                elseif (app.DH(1,1)>0 && app.DV(1,1)>0 && app.DH(1,1)<app.DV(1,1))
                    Dproblem = Dproblem + "--" + "Imbalance Suspected";
                end
                % Rotating Looseness
                if (length(app.DH(:,1))>=7 &&...
                        app.DH(1,1)>0 && app.DH(2,1)>0 && app.DH(3,1)>0 &&...
                        app.DH(4,1)>0 && app.DH(5,1)>0 && app.DH(6,1)>0 &&...
                        app.DH(7,1)>0)
                    Dproblem = Dproblem + "--" + "Rotating Looseness";
                end
                % Out of Balance
                % Check the continue increase of the data
                Db = false;
                for i = 2:length(app.PDH)
                    if (i+1) > length(app.PDH)
                        break;
                    end
                    % IF not
                    if app.PDH(i-1)<app.PDH(i) && app.PDH(i)<app.PDH(i+1)
                        Db = true;
                    else
                        Db = false;
                    end
                end
                if Db == true
                    Dproblem = Dproblem + "--" + "Out of Balance";
                end
                
                app.DriveEndProblemTextArea.Value = Dproblem;
                %% Clear temporary variables
                clear Dproblem Db i
                
                
                %% Non DRIVE END
                % Mis Alignment and Bent Shaft
                if (((app.NDH(1,1)>marginValue && app.NDH(2,1)>0) && app.NDH(3,1)>0) &&...
                        (app.NDV(1,1)>marginValue && app.NDV(2,1)>0) &&...
                        (app.NDA(1,1)>marginValue && app.NDA(2,1)>0))
                    if app.couple == "Flexible"
                        % % Mis Alignment
                        NDproblem = NDproblem + "--" + "Mis Alignment";
                    else % Bent Shaft
                        NDproblem = NDproblem + "--" + "Bent Shaft/Cocked Bearing";
                    end
                end
                % Structural Looseness
                if (app.NDH(1,1)>0 && app.NDV(1,1)>0 && app.NDH(1,1)>app.NDV(1,1))
                    NDproblem = NDproblem + "--" + "Vertical Looseness/Severe Foundation Flexibility";
                elseif (app.NDH(1,1)>0 && app.NDV(1,1)>0 && app.NDH(1,1)<app.NDV(1,1))
                    NDproblem = NDproblem + "--" + "Imbalance Suspected";
                end
                % Rotating Looseness
                if (length(app.NDH(:,1))>=7 &&...
                        app.NDH(1,1)>0 && app.NDH(2,1)>0 && app.NDH(3,1)>0 &&...
                        app.NDH(4,1)>0 && app.NDH(5,1)>0 && app.NDH(6,1)>0 &&...
                        app.NDH(7,1)>0)
                    NDproblem = NDproblem + "--" + "Rotating Looseness";
                end
                % Out of Balance
                % Check the continue increase of the data
                NDb = false;
                for i = 2:length(app.PNDH)
                    if (i+1) > length(app.PNDH)
                        break;
                    end
                    % IF not
                    if app.PNDH(i-1)<app.PNDH(i) && app.PNDH(i)<app.PNDH(i+1)
                        NDb = true;
                    else
                        NDb = false;
                    end
                end
                if NDb == true % Print Result
                    NDproblem = NDproblem + "--" + "Out of Balance";
                end
                
                app.NonDriveEndProblemTextArea.Value = NDproblem;

                %% Clear temporary variables
                clear NDproblem i NDb
            else
                app.InvalidPathLabel.Visible = 'on';
                app.InvalidPathLabel.BackgroundColor = 'green';
            end
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [fileN, pathN] = uigetfile('.xlsx');
            app.filePath = convertCharsToStrings(pathN) + convertCharsToStrings(fileN);
        end

        % Value changed function: S16RowStartEditField
        function S16RowStartEditFieldValueChanged(app, event)
            app.S16RowS = app.S16RowStartEditField.Value;
        end

        % Value changed function: S16RowEndEditField
        function S16RowEndEditFieldValueChanged(app, event)
            app.S16RowE = app.S16RowEndEditField.Value;
        end

        % Value changed function: S712RowStartEditField
        function S712RowStartEditFieldValueChanged(app, event)
            app.S712RowS = app.S712RowStartEditField.Value;
        end

        % Value changed function: S712RowEndEditField
        function S712RowEndEditFieldValueChanged(app, event)
            app.S712RowE = app.S712RowEndEditField.Value;
        end

        % Value changed function: DataSheetRangeCheckBox
        function DataSheetRangeCheckBoxValueChanged(app, event)
            if app.DataSheetRangeCheckBox.Value == false
                % Edit Field visibility off
                app.S16RowStartEditField.Visible = false;
                app.S16RowEndEditField.Visible = false;
                app.S712RowStartEditField.Visible = false;
                app.S712RowEndEditField.Visible = false;

                % Label Field visibility off
                app.S16RowStartLabel.Visible = false;
                app.S16RowEndEditFieldLabel.Visible = false;
                app.S712RowStartEditFieldLabel.Visible = false;
                app.S712RowEndEditFieldLabel.Visible = false;

                % Truen off Editable access
                app.S16RowStartEditField.Editable = false;
                app.S16RowEndEditField.Editable = false;
                app.S712RowStartEditField.Editable = false;
                app.S712RowEndEditField.Editable = false;

                % Label off
                app.Sheet1to6Label.Visible = false;
                app.Sheet7to12Label.Visible = false;
            else
                % Edit Field visibility on
                app.S16RowStartEditField.Visible = true;
                app.S16RowEndEditField.Visible = true;
                app.S712RowStartEditField.Visible = true;
                app.S712RowEndEditField.Visible = true;

                % Label Field visibility on
                app.S16RowStartLabel.Visible = true;
                app.S16RowEndEditFieldLabel.Visible = true;
                app.S712RowStartEditFieldLabel.Visible = true;
                app.S712RowEndEditFieldLabel.Visible = true;

                % Truen on Editable access
                app.S16RowStartEditField.Editable = true;
                app.S16RowEndEditField.Editable = true;
                app.S712RowStartEditField.Editable = true;
                app.S712RowEndEditField.Editable = true;

                % Label off
                app.Sheet1to6Label.Visible = true;
                app.Sheet7to12Label.Visible = true;
            end
        end

        % Value changed function: SPmCheckBox
        function SPmCheckBoxValueChanged(app, event)
            if app.SPmCheckBox.Value == true
                % Trun on the associated fields
                app.DdbcEditFieldLabel.Visible = true;
                app.DdbmEditFieldLabel.Visible = true;
                app.NDdbcEditFieldLabel.Visible = true;
                app.NDdbmEditFieldLabel.Visible = true;

                app.DdbcEditField.Visible = true;
                app.DdbmEditField.Visible = true;
                app.NDdbcEditField.Visible = true;
                app.NDdbmEditField.Visible = true;

                app.DdbcEditField.Editable = true;
                app.DdbmEditField.Editable = true;
                app.NDdbcEditField.Editable = true;
                app.NDdbmEditField.Editable = true;
            else
                % Trun off the associated fields
                app.DdbcEditFieldLabel.Visible = false;
                app.DdbmEditFieldLabel.Visible = false;
                app.NDdbcEditFieldLabel.Visible = false;
                app.NDdbmEditFieldLabel.Visible = false;

                app.DdbcEditField.Visible = false;
                app.DdbmEditField.Visible = false;
                app.NDdbcEditField.Visible = false;
                app.NDdbmEditField.Visible = false;

                app.DdbcEditField.Editable = false;
                app.DdbmEditField.Editable = false;
                app.NDdbcEditField.Editable = false;
                app.NDdbmEditField.Editable = false;
            end
        end

        % Value changed function: DdbcEditField
        function DdbcEditFieldValueChanged(app, event)
            app.Ddbc = app.DdbcEditField.Value;
        end

        % Value changed function: DdbmEditField
        function DdbmEditFieldValueChanged(app, event)
            app.Ddbm = app.DdbmEditField.Value;
        end

        % Value changed function: NDdbmEditField
        function NDdbmEditFieldValueChanged(app, event)
            app.NDdbm = app.NDdbmEditField.Value;
        end

        % Value changed function: NDdbcEditField
        function NDdbcEditFieldValueChanged(app, event)
            app.NDdbc = app.NDdbcEditField.Value;
        end

        % Value changed function: CouplingDropDown
        function CouplingDropDownValueChanged(app, event)
            app.couple = app.CouplingDropDown.Value;
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            app.SelectFigure = app.SelectDropDown.Value;
            
            % Select Figure
            if (~isempty(app.t))
                if app.SelectFigure == "All"
                    plot(app.UIAxes, table2array(app.t(:,1)), [table2array(app.t(:,2)), ...
                        table2array(app.t(:,3)), table2array(app.t(:,4)), ...
                        table2array(app.t(:,5)), table2array(app.t(:,6))]);
                    legend(app.UIAxes, 'DH', 'DV', 'DA', 'NH', 'NV', 'NA');
                elseif app.SelectFigure == "DH"
                    plot(app.UIAxes, table2array(app.t(:,1)), table2array(app.t(:,2)));
                    legend(app.UIAxes, 'DH');
                elseif app.SelectFigure == "DV"
                    plot(app.UIAxes, table2array(app.t(:,1)), table2array(app.t(:,3)));
                    legend(app.UIAxes, 'DV');
                elseif app.SelectFigure == "DA"
                    plot(app.UIAxes, table2array(app.t(:,1)), table2array(app.t(:,4)));
                    legend(app.UIAxes, 'DA');
                elseif app.SelectFigure == "NDH"
                    plot(app.UIAxes, table2array(app.t(:,1)), table2array(app.t(:,5)));
                    legend(app.UIAxes, 'NDH');
                elseif app.SelectFigure == "NDV"
                    plot(app.UIAxes, table2array(app.t(:,1)), table2array(app.t(:,6)));
                    legend(app.UIAxes, 'NDV');
                elseif app.SelectFigure == "NDA"
                    plot(app.UIAxes, table2array(app.t(:,1)), table2array(app.t(:,7)));
                    legend(app.UIAxes, 'NDA');
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.DriveEndConditionEditField = uieditfield(app.UIFigure, 'text');
            app.DriveEndConditionEditField.Editable = 'off';
            app.RPMEditFieldLabel = uilabel(app.UIFigure);
            app.RPMEditFieldLabel.Text = 'RPM';
            app.RPMEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMEditField.ValueChangedFcn = createCallbackFcn(app, @RPMEditFieldValueChanged, true);
            app.SlightEditFieldLabel = uilabel(app.UIFigure);
            app.SlightEditFieldLabel.Visible = 'off';
            app.SlightEditFieldLabel.Text = 'Slight';
            app.SlightEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlightEditField.ValueChangedFcn = createCallbackFcn(app, @SlightEditFieldValueChanged, true);
            app.SlightEditField.Editable = 'off';
            app.SlightEditField.Visible = 'off';
            app.ModerateEditFieldLabel = uilabel(app.UIFigure);
            app.ModerateEditFieldLabel.Visible = 'off';
            app.ModerateEditFieldLabel.Text = 'Moderate';
            app.ModerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModerateEditField.ValueChangedFcn = createCallbackFcn(app, @ModerateEditFieldValueChanged, true);
            app.ModerateEditField.Editable = 'off';
            app.ModerateEditField.Visible = 'off';
            app.SeriousEditFieldLabel = uilabel(app.UIFigure);
            app.SeriousEditFieldLabel.Visible = 'off';
            app.SeriousEditFieldLabel.Text = 'Serious';
            app.SeriousEditField = uieditfield(app.UIFigure, 'numeric');
            app.SeriousEditField.ValueChangedFcn = createCallbackFcn(app, @SeriousEditFieldValueChanged, true);
            app.SeriousEditField.Editable = 'off';
            app.SeriousEditField.Visible = 'off';
            app.ExtremeEditFieldLabel = uilabel(app.UIFigure);
            app.ExtremeEditFieldLabel.Visible = 'off';
            app.ExtremeEditFieldLabel.Text = 'Extreme';
            app.ExtremeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExtremeEditField.ValueChangedFcn = createCallbackFcn(app, @ExtremeEditFieldValueChanged, true);
            app.ExtremeEditField.Editable = 'off';
            app.ExtremeEditField.Visible = 'off';
            app.ImbalanceCheckBox = uicheckbox(app.UIFigure);
            app.ImbalanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImbalanceCheckBoxValueChanged, true);
            app.ImbalanceCheckBox.Text = 'Imbalance';
            app.ImbalanceCheckBox.Value = true;
            app.DayEditFieldLabel = uilabel(app.UIFigure);
            app.DayEditFieldLabel.Text = 'Day';
            app.DayEditField = uieditfield(app.UIFigure, 'numeric');
            app.DayEditField.Editable = 'off';
            app.MonthEditFieldLabel = uilabel(app.UIFigure);
            app.MonthEditFieldLabel.Text = 'Month';
            app.MonthEditField = uieditfield(app.UIFigure, 'numeric');
            app.MonthEditField.Editable = 'off';
            app.FoundationDropDownLabel = uilabel(app.UIFigure);
            app.FoundationDropDownLabel.Text = 'Foundation';
            app.FoundationDropDown = uidropdown(app.UIFigure);
            app.FoundationDropDown.Items = {'Spring', 'Rigid'};
            app.FoundationDropDown.ValueChangedFcn = createCallbackFcn(app, @FoundationDropDownValueChanged, true);
            app.FoundationDropDown.Value = 'Spring';
            app.MotorTypeDropDownLabel = uilabel(app.UIFigure);
            app.MotorTypeDropDownLabel.Text = 'Motor Type';
            app.MotorTypeDropDown = uidropdown(app.UIFigure);
            app.MotorTypeDropDown.Items = {'Over Hang'};
            app.MotorTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MotorTypeDropDownValueChanged, true);
            app.MotorTypeDropDown.Value = 'Over Hang';
            app.ValueTableCheckBox = uicheckbox(app.UIFigure);
            app.ValueTableCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueTableCheckBoxValueChanged, true);
            app.ValueTableCheckBox.Text = 'ValueTable';
            app.ValueTableCheckBox.Value = true;
            app.PlotCheckBox = uicheckbox(app.UIFigure);
            app.PlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotCheckBoxValueChanged, true);
            app.PlotCheckBox.Text = 'Plot';
            app.PlotCheckBox.Value = true;
            app.DriveEndConditionEditFieldLabel = uilabel(app.UIFigure);
            app.DriveEndConditionEditFieldLabel.Text = {'Drive End'; 'Condition'};
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'OpenFile';
            app.NonDriveEndConditionEditFieldLabel = uilabel(app.UIFigure);
            app.NonDriveEndConditionEditFieldLabel.Text = {'Non Drive End'; 'Condition'};
            app.NonDriveEndConditionEditField = uieditfield(app.UIFigure, 'text');
            app.NonDriveEndConditionEditField.Editable = 'off';
            app.DataSheetRangeCheckBox = uicheckbox(app.UIFigure);
            app.DataSheetRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @DataSheetRangeCheckBoxValueChanged, true);
            app.DataSheetRangeCheckBox.Text = 'Data Sheet Range';
            app.DataSheetRangeCheckBox.Value = true;
            app.S16RowStartLabel = uilabel(app.UIFigure);
            app.S16RowStartLabel.Visible = 'off';
            app.S16RowStartLabel.Text = 'S16 Row Start';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'F'; 'DH'; 'DV'; 'DA'; 'NDH'; 'NDV'; 'NDA'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.S16RowStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.S16RowStartEditField.ValueChangedFcn = createCallbackFcn(app, @S16RowStartEditFieldValueChanged, true);
            app.S16RowStartEditField.Editable = 'off';
            app.S16RowStartEditField.Visible = 'off';
            app.S16RowEndEditFieldLabel = uilabel(app.UIFigure);
            app.S16RowEndEditFieldLabel.Visible = 'off';
            app.S16RowEndEditFieldLabel.Text = 'S16 Row End';
            app.S16RowEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.S16RowEndEditField.ValueChangedFcn = createCallbackFcn(app, @S16RowEndEditFieldValueChanged, true);
            app.S16RowEndEditField.Editable = 'off';
            app.S16RowEndEditField.Visible = 'off';
            app.Sheet1to6Label = uilabel(app.UIFigure);
            app.Sheet1to6Label.Visible = 'off';
            app.Sheet1to6Label.Text = 'Sheet 1 to 6,';
            app.Sheet7to12Label = uilabel(app.UIFigure);
            app.Sheet7to12Label.Visible = 'off';
            app.Sheet7to12Label.Text = 'Sheet 7 to 12,';
            app.S712RowStartEditFieldLabel = uilabel(app.UIFigure);
            app.S712RowStartEditFieldLabel.Visible = 'off';
            app.S712RowStartEditFieldLabel.Text = 'S712 Row Start';
            app.S712RowStartEditField = uieditfield(app.UIFigure, 'numeric');
            app.S712RowStartEditField.ValueChangedFcn = createCallbackFcn(app, @S712RowStartEditFieldValueChanged, true);
            app.S712RowStartEditField.Editable = 'off';
            app.S712RowStartEditField.Visible = 'off';
            app.S712RowEndEditFieldLabel = uilabel(app.UIFigure);
            app.S712RowEndEditFieldLabel.Visible = 'off';
            app.S712RowEndEditFieldLabel.Text = 'S712 Row End';
            app.S712RowEndEditField = uieditfield(app.UIFigure, 'numeric');
            app.S712RowEndEditField.ValueChangedFcn = createCallbackFcn(app, @S712RowEndEditFieldValueChanged, true);
            app.S712RowEndEditField.Editable = 'off';
            app.S712RowEndEditField.Visible = 'off';
            app.SPmCheckBox = uicheckbox(app.UIFigure);
            app.SPmCheckBox.ValueChangedFcn = createCallbackFcn(app, @SPmCheckBoxValueChanged, true);
            app.SPmCheckBox.Text = 'SPm';
            app.SPmCheckBox.Value = true;
            app.DdbcEditFieldLabel = uilabel(app.UIFigure);
            app.DdbcEditFieldLabel.Visible = 'off';
            app.DdbcEditFieldLabel.Text = 'Ddbc';
            app.DdbcEditField = uieditfield(app.UIFigure, 'numeric');
            app.DdbcEditField.ValueChangedFcn = createCallbackFcn(app, @DdbcEditFieldValueChanged, true);
            app.DdbcEditField.Editable = 'off';
            app.DdbcEditField.Visible = 'off';
            app.DdbmEditFieldLabel = uilabel(app.UIFigure);
            app.DdbmEditFieldLabel.Visible = 'off';
            app.DdbmEditFieldLabel.Text = 'Ddbm';
            app.DdbmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DdbmEditField.ValueChangedFcn = createCallbackFcn(app, @DdbmEditFieldValueChanged, true);
            app.DdbmEditField.Editable = 'off';
            app.DdbmEditField.Visible = 'off';
            app.NDdbmEditFieldLabel = uilabel(app.UIFigure);
            app.NDdbmEditFieldLabel.Visible = 'off';
            app.NDdbmEditFieldLabel.Text = 'NDdbm';
            app.NDdbmEditField = uieditfield(app.UIFigure, 'numeric');
            app.NDdbmEditField.ValueChangedFcn = createCallbackFcn(app, @NDdbmEditFieldValueChanged, true);
            app.NDdbmEditField.Editable = 'off';
            app.NDdbmEditField.Visible = 'off';
            app.NDdbcEditFieldLabel = uilabel(app.UIFigure);
            app.NDdbcEditFieldLabel.Visible = 'off';
            app.NDdbcEditFieldLabel.Text = 'NDdbc';
            app.NDdbcEditField = uieditfield(app.UIFigure, 'numeric');
            app.NDdbcEditField.ValueChangedFcn = createCallbackFcn(app, @NDdbcEditFieldValueChanged, true);
            app.NDdbcEditField.Editable = 'off';
            app.NDdbcEditField.Visible = 'off';
            app.DriveEndBearingEditFieldLabel = uilabel(app.UIFigure);
            app.DriveEndBearingEditFieldLabel.Text = {'Drive End'; 'Bearing'};
            app.DriveEndBearingEditField = uieditfield(app.UIFigure, 'text');
            app.DriveEndBearingEditField.Editable = 'off';
            app.NonDriveEndBearingEditFieldLabel = uilabel(app.UIFigure);
            app.NonDriveEndBearingEditFieldLabel.Text = {'Non Drive End'; 'Bearing'};
            app.NonDriveEndBearingEditField = uieditfield(app.UIFigure, 'text');
            app.NonDriveEndBearingEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.DriveEndProblemTextAreaLabel = uilabel(app.UIFigure);
            app.DriveEndProblemTextAreaLabel.Text = {'Drive End'; 'Problem'};
            app.DriveEndProblemTextArea = uitextarea(app.UIFigure);
            app.DriveEndProblemTextArea.Editable = 'off';
            app.NonDriveEndProblemTextAreaLabel = uilabel(app.UIFigure);
            app.NonDriveEndProblemTextAreaLabel.Text = {'Non Drive End'; 'Problem'};
            app.NonDriveEndProblemTextArea = uitextarea(app.UIFigure);
            app.NonDriveEndProblemTextArea.Editable = 'off';
            app.InvalidPathLabel = uilabel(app.UIFigure);
            app.InvalidPathLabel.Visible = 'off';
            app.InvalidPathLabel.Text = '****Invalid Path****';
            app.CouplingDropDownLabel = uilabel(app.UIFigure);
            app.CouplingDropDownLabel.Text = 'Coupling';
            app.CouplingDropDown = uidropdown(app.UIFigure);
            app.CouplingDropDown.Items = {'Integrated', 'Flexible'};
            app.CouplingDropDown.ValueChangedFcn = createCallbackFcn(app, @CouplingDropDownValueChanged, true);
            app.CouplingDropDown.Value = 'Integrated';
            app.SelectDropDownLabel = uilabel(app.UIFigure);
            app.SelectDropDownLabel.Text = 'Select';
            app.SelectDropDown = uidropdown(app.UIFigure);
            app.SelectDropDown.Items = {'All', 'DH', 'DV', 'DA', 'NDH', 'NDV', 'NDA'};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = 'All';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = codeBaseV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end